classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FramerateEditField       matlab.ui.control.NumericEditField
        FramerateEditFieldLabel  matlab.ui.control.Label
        PauseButton              matlab.ui.control.Button
        PlayButton               matlab.ui.control.Button
        Image                    matlab.ui.control.Image
        BrowseVideosButton       matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

     properties (Access = private)
        vid_total_frames
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseVideosButton
        function BrowseVideosButtonPushed(app, event)
            [video_filename,pathname] = uigetfile({'*.MOV;*.avi;*.MP4;*.seq','Video files'},'pick file');
            video_file = fullfile(pathname,video_filename);
            % Extract the file name and extension
            [~, video_name, video_extension] = fileparts(video_file);

            % Create a VideoReader object for the video file
            video = VideoReader(video_file);

            % calculate proprties of the video

            app.vid_total_frames = video.NumFrames;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            disp(app.vid_total_frames);
        end

        % Value changed function: FramerateEditField
        function FramerateEditFieldValueChanged(app, event)
            value = app.FramerateEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.BrowseVideosButton = uibutton(app.UIFigure, 'push');
            app.BrowseVideosButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseVideosButtonPushed, true);
            app.BrowseVideosButton.Text = 'BrowseVideos';
            app.Image = uiimage(app.UIFigure);
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.Text = 'Pause';
            app.FramerateEditFieldLabel = uilabel(app.UIFigure);
            app.FramerateEditFieldLabel.Text = 'Frame rate';
            app.FramerateEditField = uieditfield(app.UIFigure, 'numeric');
            app.FramerateEditField.ValueChangedFcn = createCallbackFcn(app, @FramerateEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end